classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Pay2StayUIFigure           matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        PayoffDropDownLabel        matlab.ui.control.Label
        PayoffDropDown             matlab.ui.control.DropDown
        EliminationDropDownLabel   matlab.ui.control.Label
        EliminationDropDown        matlab.ui.control.DropDown
        SimulationSwitchLabel      matlab.ui.control.Label
        SimulationSwitch           matlab.ui.control.ToggleSwitch
        ResetButton                matlab.ui.control.Button
        Pay2WinLabel               matlab.ui.control.Label
        StepButton                 matlab.ui.control.Button
        CoinsEditFieldLabel        matlab.ui.control.Label
        CoinsEditField             matlab.ui.control.NumericEditField
        MutationCheckBox           matlab.ui.control.CheckBox
        GenerationsEditFieldLabel  matlab.ui.control.Label
        GenerationsEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        model = GameGrid();
        generation = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.model.init();
            app.model.setGenerations(10);
            app.model.setCoins(10);
            app.model.setMutate(false);
            app.ResetButtonPushed(0);
        end

        % Value changed function: EliminationDropDown, PayoffDropDown
        function PayoffDropDownValueChanged(app, event)
            value = app.PayoffDropDown.Value;
            app.model.setPayoff(value); % Payoff rule changed, tell game to change!
        end

        % Value changed function: EliminationDropDown
        function EliminationDropDownValueChanged(app, event)
            value = app.EliminationDropDown.Value;
            app.model.setElimination(value); % Elimination rule changed, tell game to change!
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.model.reset(); % Reset button was pressed to reset model
            app.generation = 1;
            app.StepButtonPushed(event);
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            app.model.step(app.generation); % Step button was pressed to step the model
            
            % Update image of simulation
            frame = app.model.getFrame(app.generation);
            app.generation = app.generation + 1;
            image(frame,'Parent',app.UIAxes);
            
            % Update bar-diagram
            strategies = reshape(app.model.strategyGrid,[],app.model.nCoins+1);
            bar(0:app.model.nCoins,mean(strategies), 'Parent', app.UIAxes2);
            errorbar(0:app.model.nCoins,mean(strategies),std(strategies),'.','LineWidth',2, 'Parent', app.UIAxes2);
        end

        % Value changed function: SimulationSwitch
        function SimulationSwitchValueChanged(app, event)
            value = app.SimulationSwitch.Value
            if(strcmp(value, 'On'))
                for i=1:app.model.getGenerations()
                    app.StepButtonPushed(event);
                    pause(1/20);
                end
            end
        end

        % Value changed function: CoinsEditField
        function CoinsEditFieldValueChanged(app, event)
            value = app.CoinsEditField.Value;
            app.model.setCoins(value);
            app.ResetButtonPushed(event);
        end

        % Value changed function: MutationCheckBox
        function MutationCheckBoxValueChanged(app, event)
            value = app.MutationCheckBox.Value;
            app.model.setMutate(value);
        end

        % Value changed function: GenerationsEditField
        function GenerationsEditFieldValueChanged(app, event)
            value = app.GenerationsEditField.Value;
            app.model.setGenerations(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Pay2StayUIFigure = uifigure;
            app.Pay2StayUIFigure.Name = 'Pay2Stay';
            app.UIAxes = uiaxes(app.Pay2StayUIFigure);
            title(app.UIAxes, 'Population on grid')
            app.UIAxes2 = uiaxes(app.Pay2StayUIFigure);
            title(app.UIAxes2, 'Strategy population')
            xlabel(app.UIAxes2, 'distribution')
            app.PayoffDropDownLabel = uilabel(app.Pay2StayUIFigure);
            app.PayoffDropDownLabel.Text = 'Payoff';
            app.PayoffDropDown = uidropdown(app.Pay2StayUIFigure);
            app.PayoffDropDown.Items = {'Simple', 'Linear', 'Non-linear'};
            app.PayoffDropDown.ValueChangedFcn = createCallbackFcn(app, @PayoffDropDownValueChanged, true);
            app.PayoffDropDown.Value = 'Simple';
            app.EliminationDropDownLabel = uilabel(app.Pay2StayUIFigure);
            app.EliminationDropDownLabel.Text = 'Elimination';
            app.EliminationDropDown = uidropdown(app.Pay2StayUIFigure);
            app.EliminationDropDown.Items = {'Full', 'Random'};
            app.EliminationDropDown.ValueChangedFcn = createCallbackFcn(app, @PayoffDropDownValueChanged, true);
            app.EliminationDropDown.Value = 'Full';
            app.SimulationSwitchLabel = uilabel(app.Pay2StayUIFigure);
            app.SimulationSwitchLabel.Text = 'Simulation';
            app.SimulationSwitch = uiswitch(app.Pay2StayUIFigure, 'toggle');
            app.SimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @SimulationSwitchValueChanged, true);
            app.ResetButton = uibutton(app.Pay2StayUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Pay2WinLabel = uilabel(app.Pay2StayUIFigure);
            app.Pay2WinLabel.Text = 'Pay2Win';
            app.StepButton = uibutton(app.Pay2StayUIFigure, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Text = 'Step';
            app.CoinsEditFieldLabel = uilabel(app.Pay2StayUIFigure);
            app.CoinsEditFieldLabel.Text = 'Coins';
            app.CoinsEditField = uieditfield(app.Pay2StayUIFigure, 'numeric');
            app.CoinsEditField.Limits = [1 Inf];
            app.CoinsEditField.ValueChangedFcn = createCallbackFcn(app, @CoinsEditFieldValueChanged, true);
            app.CoinsEditField.Value = 10;
            app.MutationCheckBox = uicheckbox(app.Pay2StayUIFigure);
            app.MutationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MutationCheckBoxValueChanged, true);
            app.MutationCheckBox.Text = 'Mutation';
            app.GenerationsEditFieldLabel = uilabel(app.Pay2StayUIFigure);
            app.GenerationsEditFieldLabel.Text = 'Generations';
            app.GenerationsEditField = uieditfield(app.Pay2StayUIFigure, 'numeric');
            app.GenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @GenerationsEditFieldValueChanged, true);
            app.GenerationsEditField.Value = 10;
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Pay2StayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Pay2StayUIFigure)
        end
    end
end